classdef dFC_toolbox_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DynamicFunctionalConnectivityToolboxUIFigure  matlab.ui.Figure
        TextArea                        matlab.ui.control.TextArea
        TransitionThresholdSpinner      matlab.ui.control.Spinner
        TransitionThresholdSpinnerLabel  matlab.ui.control.Label
        ExportResultsButton             matlab.ui.control.Button
        ColorSchemeDropDown             matlab.ui.control.DropDown
        ColorSchemeDropDownLabel        matlab.ui.control.Label
        ExportCurrentFigureButton       matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        AllRegionsCheckBox              matlab.ui.control.CheckBox
        DefaultModeNetworkCheckBox      matlab.ui.control.CheckBox
        SalienceNetworkCheckBox         matlab.ui.control.CheckBox
        DorsalAttentionNetworkCheckBox  matlab.ui.control.CheckBox
        LanguageNetworkCheckBox         matlab.ui.control.CheckBox
        HideOutlinesButton              matlab.ui.control.StateButton
        ThresholdSpinner                matlab.ui.control.Spinner
        ThresholdSpinnerLabel           matlab.ui.control.Label
        FigureCtrlListBox               matlab.ui.control.ListBox
        InterwindowSimilarityPanel      matlab.ui.container.Panel
        ViewModeButtonGroup             matlab.ui.container.ButtonGroup
        StatesAnalysisButton            matlab.ui.control.RadioButton
        TemporalMetricsButton           matlab.ui.control.RadioButton
        Image                           matlab.ui.control.Image
        RunStatesAnalysisButton         matlab.ui.control.Button
        ComputeTemporalMetricsButton    matlab.ui.control.Button
        LoadexistingprojectButton       matlab.ui.control.Button
        StartnewprojectButton           matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        dFC_project struct % Description
        center_x double % Description
        center_y double % Description
        rois double % Description
        net_comm cell % Description
        net_ncomm cell % Description
        comm cell % Description
        ncomm double % Description
        user_pref struct % Description
        plot_color char % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.DynamicFunctionalConnectivityToolboxUIFigure,"center")
            app.dFC_project = struct();
            app.dFC_project.app_threshold = 0.1;

            % screen size
            sz = get( 0, 'ScreenSize');
            
            % center position
            app.center_x = mean( sz( [1, 3]));
            app.center_y = mean( sz( [2, 4]));

            app.rois = zeros(166+34+10+8+10,1);
            
            old_path = pwd;
            cd(strrep(which('dFC_toolbox_app'),'\dFC_toolbox_app.mlapp',''))
            if exist('user_pref.mat','file')
                app.user_pref = load('user_pref.mat').user_pref;
            else
                app.user_pref = struct();
            end
            cd(old_path)
            addpath(genpath(strrep(which('dFC_toolbox_app'),'\dFC_toolbox_app.mlapp','')))

            app.plot_color = 'default';
        end

        % Button pushed function: StartnewprojectButton
        function StartnewprojectButtonPushed(app, event)
            try
                new_project
                app.dFC_project = dFC_project;
                figure(app.DynamicFunctionalConnectivityToolboxUIFigure)
                app.RunStatesAnalysisButton.Enable = 'on';
                app.Image.Visible = 'off';
                app.ColorSchemeDropDownLabel.Visible = 'off';
                app.AllRegionsCheckBox.Visible = 'off';
                app.LanguageNetworkCheckBox.Visible = 'off';
                app.DorsalAttentionNetworkCheckBox.Visible = 'off';
                app.SalienceNetworkCheckBox.Visible = 'off';
                app.DefaultModeNetworkCheckBox.Visible = 'off';
                app.StatesAnalysisButton.Enable = 'off';
                app.ViewModeButtonGroup.Visible = 'on';
                app.TemporalMetricsButton.Value = 1;
                cla(app.UIAxes)
                cla(app.UIAxes2)
                cla(app.UIAxes3)
                cla(app.UIAxes4)
                app.UIAxes.Visible = 'off';
                app.UIAxes2.Visible = 'off';
                app.UIAxes3.Visible = 'off';
                app.UIAxes4.Visible = 'off';
                app.ThresholdSpinner.Visible = 'off';
                app.ThresholdSpinnerLabel.Visible = 'off';
                app.ColorSchemeDropDown.Visible = 'off';
                app.ColorSchemeDropDownLabel.Visible = 'off';

                % Figure Control List Box
                app.FigureCtrlListBox.Visible = 'on';
                nsub = app.dFC_project.nsub;
                choices = cellstr(num2str(transpose([1:nsub])));
                choices = cellfun(@(x) ['Subject ',x],choices,...
                    'UniformOutput',false);
                app.FigureCtrlListBox.Items = choices;
                app.FigureCtrlListBox.Value = app.FigureCtrlListBox.Items{1};
                value = app.FigureCtrlListBox.Value;
                subject = str2double(regexp(value,'\d*','Match'));

                % Inter-window Similarity Panel
                app.InterwindowSimilarityPanel.Visible = 'on';
                upperlim = min(1,2*mean(app.dFC_project.comm_sim(:,:,subject),'all'));
                h = heatmap(app.InterwindowSimilarityPanel,...
                    app.dFC_project.comm_sim_states(:,:,subject), ...
                    'ColorLimits', [0 1],'Colormap',pink);
                h.GridVisible = 'off';
                labels = cell(size(app.dFC_project.comm_sim_states,1),1);
                labels(:) = {''};
                h.XDisplayLabels = labels;
                h.YDisplayLabels = labels;
                h.XLabel = 'Time Window';
                h.YLabel = 'Time Window';
                app.HideOutlinesButton.Visible = 'on';
                app.TransitionThresholdSpinner.Visible = 'on';
                sub_value = app.FigureCtrlListBox.Value;
                subject = str2double(regexp(sub_value,'\d*','Match'));
                app.TransitionThresholdSpinner.Value = app.dFC_project.transition_threshold(subject);

                % Results Table
                app.UITable.Data = cell(4,2);
                app.UITable.Data{1,1} = 'Temporal Variability (TV)';
                app.UITable.Data{1,2} = num2str(round(app.dFC_project.mean_var(subject),3));
                app.UITable.Data{2,1} = 'TV Definition';
                app.UITable.Data{2,2} = app.dFC_project.tv_def;
                app.UITable.Data{3,1} = 'Transition Frequency (TF)';
                app.UITable.Data{3,2} = [num2str(round(app.dFC_project.TF_comm_struct(subject),2)),'/min'];
                app.UITable.Data{4,1} = 'Mean Inter-Window Similarity (IWS)';
                app.UITable.Data{4,2} = [num2str(round(app.dFC_project.mean_similarity(subject),2)),' s'];
                % app.UITable.Data{5,1} = 'Mean sequence duration';
                % app.UITable.Data{5,2} = [num2str(round(app.dFC_project.mean_duration(subject),2)),' s'];
                app.UITable.ColumnWidth = {[123] [55]};
                app.UITable.Visible = 'on';
                app.ComputeTemporalMetricsButton.Enable = 'on';
                app.ExportResultsButton.Enable = 'on';
                app.ExportCurrentFigureButton.Enable = 'on';
                
            catch
                waitfor(errordlg(lasterr))
                figure(app.DynamicFunctionalConnectivityToolboxUIFigure)
            end
        end

        % Button pushed function: LoadexistingprojectButton
        function LoadexistingprojectButtonPushed(app, event)
            try
                load_project
                app.dFC_project = dFC_project;
                disp([dFC_project.project_name,' loaded successfully'])
                figure(app.DynamicFunctionalConnectivityToolboxUIFigure)
                app.RunStatesAnalysisButton.Enable = 'on';
                app.Image.Visible = 'off';
                app.ViewModeButtonGroup.Visible = 'on';
                app.TemporalMetricsButton.Value = 1;
                app.ThresholdSpinner.Visible = 'off';
                app.ThresholdSpinnerLabel.Visible = 'off';
                app.ColorSchemeDropDown.Visible = 'off';
                app.ColorSchemeDropDownLabel.Visible = 'off';
                app.ColorSchemeDropDownLabel.Visible = 'off';
                app.AllRegionsCheckBox.Visible = 'off';
                app.LanguageNetworkCheckBox.Visible = 'off';
                app.DorsalAttentionNetworkCheckBox.Visible = 'off';
                app.SalienceNetworkCheckBox.Visible = 'off';
                app.DefaultModeNetworkCheckBox.Visible = 'off';
                cla(app.UIAxes)
                cla(app.UIAxes2)
                cla(app.UIAxes3)
                cla(app.UIAxes4)
                app.UIAxes.Visible = 'off';
                app.UIAxes2.Visible = 'off';
                app.UIAxes3.Visible = 'off';
                app.UIAxes4.Visible = 'off';

                % Figure Control List Box
                app.FigureCtrlListBox.Visible = 'on';
                nsub = app.dFC_project.nsub;
                choices = cellstr(num2str(transpose([1:nsub])));
                choices = cellfun(@(x) ['Subject ',x],choices,...
                    'UniformOutput',false);
                app.FigureCtrlListBox.Items = choices;
                app.FigureCtrlListBox.Value = app.FigureCtrlListBox.Items{1};
                value = app.FigureCtrlListBox.Value;
                subject = str2double(regexp(value,'\d*','Match'));

                % Inter-window Similarity Panel
                app.InterwindowSimilarityPanel.Visible = 'on';
                upperlim = min(1,2*mean(app.dFC_project.comm_sim(:,:,subject),'all'));
                h = heatmap(app.InterwindowSimilarityPanel, ...
                    app.dFC_project.comm_sim_states(:,:,subject), ...
                    'ColorLimits', [0 1],'Colormap',pink);
                h.GridVisible = 'off';
                labels = cell(size(app.dFC_project.comm_sim_states,1),1);
                labels(:) = {''};
                h.XDisplayLabels = labels;
                h.YDisplayLabels = labels;
                h.XLabel = 'Time Window';
                h.YLabel = 'Time Window';
                app.HideOutlinesButton.Visible = 'on';
                app.TransitionThresholdSpinner.Visible = 'on';
                sub_value = app.FigureCtrlListBox.Value;
                subject = str2double(regexp(sub_value,'\d*','Match'));
                app.TransitionThresholdSpinner.Value = app.dFC_project.transition_threshold(subject);

                % View Mode Button Group
                if isfield(app.dFC_project,'state_dFC_mean')
                    app.StatesAnalysisButton.Enable = 'on';
                else
                    app.StatesAnalysisButton.Enable = 'off';
                end

                % Results Table
                app.UITable.Data = cell(4,2);
                app.UITable.Data{1,1} = 'Temporal Variability (TV)';
                app.UITable.Data{1,2} = num2str(round(app.dFC_project.mean_var(subject),3));
                app.UITable.Data{2,1} = 'TV Definition';
                app.UITable.Data{2,2} = app.dFC_project.tv_def;
                app.UITable.Data{3,1} = 'Transition Frequency (TF)';
                app.UITable.Data{3,2} = [num2str(round(app.dFC_project.TF_comm_struct(subject),2)),'/min'];
                app.UITable.Data{4,1} = 'Mean Inter-Window Similarity';
                app.UITable.Data{4,2} = [num2str(round(app.dFC_project.mean_similarity(subject),2)),' s'];
                % app.UITable.Data{5,1} = 'Mean sequence duration';
                % app.UITable.Data{5,2} = [num2str(round(app.dFC_project.mean_duration(subject),2)),' s'];
                app.UITable.ColumnWidth = {[123] [55]};
                app.UITable.Visible = 'on';
                app.ComputeTemporalMetricsButton.Enable = 'on';
                app.ExportResultsButton.Enable = 'on';
                app.ExportCurrentFigureButton.Enable = 'on';
                app.TextArea.Visible = 'on';
                app.TextArea.Value = app.dFC_project.project_name;
                
            catch
                waitfor(errordlg(lasterr))
                figure(app.DynamicFunctionalConnectivityToolboxUIFigure)
            end
        end

        % Button pushed function: ComputeTemporalMetricsButton
        function ComputeTemporalMetricsButtonPushed(app, event)
            try
                app.dFC_project = compute_variability(app.dFC_project);
                figure(app.DynamicFunctionalConnectivityToolboxUIFigure)
                app.dFC_project = compute_TF_comm_struct_v4(app.dFC_project,1);
                figure(app.DynamicFunctionalConnectivityToolboxUIFigure)
                dFC_project = app.dFC_project;
                ViewModeButtonGroupSelectionChanged(app)
                dFC_project.project_name = char(inputdlg('Enter a name for your project ', 'Save Project...', [1 45]));
                save_path = which('findme');
                cd(save_path(1:length(save_path)-9));
                
                if exist([dFC_project.project_name,'.mat'],'file')
                    delete([dFC_project.project_name,'.mat'])
                end

                x = app.center_x;
                y = app.center_y;
                fig = uifigure('Position',[x-85 y-35 170 70]);
                uitextarea(fig,'Value','Saving Project. Please wait...','Position',[10 10 150 50]);
                drawnow
                figure(app.DynamicFunctionalConnectivityToolboxUIFigure)
                figure(fig)
                pause(0.01)
                
                if whos('dFC_project').bytes<2000000000
                    save([dFC_project.project_name,'.mat'], 'dFC_project')
                else
                    save([dFC_project.project_name,'.mat'], 'dFC_project','-v7.3')
                end
                
                close(fig)

                FigureCtrlListBoxValueChanged(app)
            catch
                waitfor(errordlg(lasterr))
                figure(app.DynamicFunctionalConnectivityToolboxUIFigure)
            end
        end

        % Button pushed function: RunStatesAnalysisButton
        function RunStatesAnalysisButtonPushed(app, event)
            if isfield(app.dFC_project,'dFC')
                try
                    dFC_project = app.dFC_project;
                    run_kmeans
                    app.dFC_project = dFC_project;
                    figure(app.DynamicFunctionalConnectivityToolboxUIFigure)
                    app.StatesAnalysisButton.Enable = 'on';
                catch
                    waitfor(errordlg(lasterr))
                    figure(app.DynamicFunctionalConnectivityToolboxUIFigure)
                end
            end
        end

        % Value changed function: FigureCtrlListBox
        function FigureCtrlListBoxValueChanged(app, event)
            value = app.FigureCtrlListBox.Value;
            if strcmp(value,'States Analysis Summary')
                cla(app.UIAxes)
                cla(app.UIAxes4)
                app.UIAxes.Visible = 'off';
                app.ThresholdSpinner.Visible = 'off';
                app.ThresholdSpinnerLabel.Visible = 'off';
                app.ColorSchemeDropDown.Visible = 'off';
                app.ColorSchemeDropDownLabel.Visible = 'off';
                app.AllRegionsCheckBox.Visible = 'off';
                app.LanguageNetworkCheckBox.Visible = 'off';
                app.DorsalAttentionNetworkCheckBox.Visible = 'off';
                app.SalienceNetworkCheckBox.Visible = 'off';
                app.DefaultModeNetworkCheckBox.Visible = 'off';
                app.UITable.Visible = 'off';
                app.UIAxes4.Visible = 'off';
                app.UIAxes2.Visible = 'on';
                app.UIAxes3.Visible = 'on';
                X = app.dFC_project.elbow_plot_k;
                Y = app.dFC_project.elbow_plot_swss; 
                plot(app.UIAxes3,X,Y)
                hold(app.UIAxes3,'on')
                nstate = max(app.dFC_project.clusters);
                plot(app.UIAxes3,[nstate nstate],[min(Y) max(Y)])
                hold(app.UIAxes3,'off')
                xlabel(app.UIAxes3,'Number of States (k)')
                ylabel(app.UIAxes3,'Total Within-Cluster Sum of Squares')
                PC1 = app.dFC_project.PC1;
                PC2 = app.dFC_project.PC2;
                colors = hsv;
                clusters = app.dFC_project.clusters;
                hold(app.UIAxes2,'on')
                for i=1:max(clusters)
                    color = colors(i*floor(256/max(clusters)),:);
                    scatter(app.UIAxes2,PC1(clusters==i),PC2(clusters==i),2,color,'filled')
                end
                hold(app.UIAxes2,'off')
                xlabel(app.UIAxes2,'PC1')
                ylabel(app.UIAxes2,'PC2')
            else
                subject = str2double(regexp(value,'\d*','Match'));
                if app.TemporalMetricsButton.Value
                    upperlim = min(1,2*mean(app.dFC_project.comm_sim(:,:,subject),'all'));
                    h = heatmap(app.InterwindowSimilarityPanel,...
                        app.dFC_project.comm_sim_states(:,:,subject), ...
                        'ColorLimits', [0 1],'Colormap',pink);
                    h.GridVisible = 'off';
                    labels = cell(size(app.dFC_project.comm_sim_states,1),1);
                    labels(:) = {''};
                    h.XDisplayLabels = labels;
                    h.YDisplayLabels = labels;
                    h.XLabel = 'Time Window';
                    h.YLabel = 'Time Window';

                    % Results Table
                    app.UITable.Data = cell(4,2);
                    app.UITable.Data{1,1} = 'Temporal Variability (TV)';
                    app.UITable.Data{1,2} = num2str(round(app.dFC_project.mean_var(subject),3));
                    app.UITable.Data{2,1} = 'TV Definition';
                    app.UITable.Data{2,2} = app.dFC_project.tv_def;
                    app.UITable.Data{3,1} = 'Transition Frequency (TF)';
                    app.UITable.Data{3,2} = [num2str(round(app.dFC_project.TF_comm_struct(subject),2)),'/min'];
                    app.UITable.Data{4,1} = 'Mean Inter-Window Similarity (IWS)';
                    app.UITable.Data{4,2} = [num2str(round(app.dFC_project.mean_similarity(subject),2)),' s'];
                    % app.UITable.Data{5,1} = 'Mean sequence duration';
                    % app.UITable.Data{5,2} = [num2str(round(app.dFC_project.mean_duration(subject),2)),' s'];
                    app.TransitionThresholdSpinner.Value =  app.dFC_project.transition_threshold(subject);
                    
                elseif app.StatesAnalysisButton.Value
                    state = subject;
                    app.UIAxes.Visible = 'on';
                    app.ThresholdSpinner.Visible = 'on';
                    app.ColorSchemeDropDown.Visible = 'on';
                    app.ColorSchemeDropDownLabel.Visible = 'on';
                    app.ThresholdSpinnerLabel.Visible = 'on';
                    app.UIAxes4.Visible = 'on';
                    cla(app.UIAxes2)
                    cla(app.UIAxes3)
                    cla(app.UIAxes4)
                    app.UIAxes2.Visible = 'off';
                    app.UIAxes3.Visible = 'off';
                    app.UITable.Visible = 'off';
                    cla(app.UIAxes)
                    nroi = size(app.dFC_project.dFC,1);
                    if app.dFC_project.atlas==1 && (nroi==166 || (nroi == 78 && app.dFC_project.which_hemi>1))
                        app.AllRegionsCheckBox.Visible = 'on';
                        app.LanguageNetworkCheckBox.Visible = 'on';
                        app.DorsalAttentionNetworkCheckBox.Visible = 'on';
                        app.SalienceNetworkCheckBox.Visible = 'on';
                        app.DefaultModeNetworkCheckBox.Visible = 'on';
                        rois = [];
                        app.AllRegionsCheckBox.Value = 0;
                    elseif app.dFC_project.atlas==1 && nroi==86
                        app.AllRegionsCheckBox.Visible = 'on';
                        app.LanguageNetworkCheckBox.Visible = 'on';
                        app.DorsalAttentionNetworkCheckBox.Visible = 'on';
                        app.SalienceNetworkCheckBox.Visible = 'on';
                        app.DefaultModeNetworkCheckBox.Visible = 'on';
                        rois = [];
                        app.AllRegionsCheckBox.Value = 0;
                    else
                        rois = app.dFC_project.include_hemi_sorted;
                    end
                    pause(0.01)
                    rois = sort(unique(app.rois(app.rois~=0)));
                    app.dFC_project.app_threshold = app.ThresholdSpinner.Value;
                    try
                        plot_on_brain_v3_inline_app
                    catch
                        errordlg(lasterr)
                    end
                end
            end
        end

        % Selection changed function: ViewModeButtonGroup
        function ViewModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ViewModeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'States Analysis')
                app.InterwindowSimilarityPanel.Visible = 'off';
                app.UIAxes.Visible = 'on';
                app.ThresholdSpinner.Visible = 'on';
                app.ColorSchemeDropDown.Visible = 'on';
                app.ColorSchemeDropDownLabel.Visible = 'on';
                app.ThresholdSpinnerLabel.Visible = 'on';
                app.HideOutlinesButton.Visible = 'off';
                app.TransitionThresholdSpinner.Visible = 'off';
                app.UITable.Visible = 'off';

                % Detect community structure of states
                nstate = numel(app.dFC_project.state_dFC_mean);
                % all_net_comm_struct
                % app.net_comm = net_comm;
                % app.net_ncomm = net_ncomm;

                % Figure Control List Box
                choices = cellstr(num2str(transpose([1:nstate])));
                choices = cellfun(@(x) ['State ',x],choices,...
                    'UniformOutput',false);
                choices(2:nstate+1) = choices;
                choices{1} = 'States Analysis Summary';
                app.FigureCtrlListBox.Items = choices;
                app.FigureCtrlListBox.Value = app.FigureCtrlListBox.Items{1};
                value = app.FigureCtrlListBox.Value;
                if strcmp(value,'States Analysis Summary')
                    cla(app.UIAxes)
                    app.UIAxes.Visible = 'off';
                    app.ThresholdSpinner.Visible = 'off';
                    app.ThresholdSpinnerLabel.Visible = 'off';
                    app.ColorSchemeDropDown.Visible = 'off';
                    app.ColorSchemeDropDownLabel.Visible = 'off';
                    app.UIAxes4.Visible = 'off';
                    app.UIAxes2.Visible = 'on';
                    app.UIAxes3.Visible = 'on';
                    X = app.dFC_project.elbow_plot_k;
                    Y = app.dFC_project.elbow_plot_swss; 
                    plot(app.UIAxes3,X,Y)
                    hold(app.UIAxes3,'on')
                    nstate = max(app.dFC_project.clusters);
                    plot(app.UIAxes3,[nstate nstate],[min(Y) max(Y)])
                    hold(app.UIAxes3,'off')
                    xlabel(app.UIAxes3,'Number of States (k)')
                    ylabel(app.UIAxes3,'Total Within-Cluster Sum of Squares')
                    PC1 = app.dFC_project.PC1;
                    PC2 = app.dFC_project.PC2;
                    colors = hsv;
                    clusters = app.dFC_project.clusters;
                    hold(app.UIAxes2,'on')
                    for i=1:max(clusters)
                        color = colors(i*floor(256/max(clusters)),:);
                        scatter(app.UIAxes2,PC1(clusters==i),PC2(clusters==i),2,color,'filled')
                    end
                    hold(app.UIAxes2,'off')
                    xlabel(app.UIAxes2,'PC1')
                    ylabel(app.UIAxes2,'PC2')
                else
                    app.UIAxes.Visible = 'on';
                    app.UIAxes4.Visible = 'on';
                    app.ThresholdSpinner.Visible = 'on';
                    app.ColorSchemeDropDown.Visible = 'on';
                    app.ColorSchemeDropDownLabel.Visible = 'on';
                    app.ThresholdSpinnerLabel.Visible = 'on';
                    cla(app.UIAxes2)
                    cla(app.UIAxes3)
                    cla(app.UIAxes4)
                    app.UIAxes2.Visible = 'off';
                    app.UIAxes3.Visible = 'off';
                    state = str2double(regexp(value,'\d*','Match'));
                    cla(app.UIAxes)
                    nroi = size(app.dFC_project.dFC,1);
                    if app.dFC_project.atlas==1 && nroi==166
                        app.AllRegionsCheckBox.Visible = 'on';
                        app.LanguageNetworkCheckBox.Visible = 'on';
                        app.DorsalAttentionNetworkCheckBox.Visible = 'on';
                        app.SalienceNetworkCheckBox.Visible = 'on';
                        app.DefaultModeNetworkCheckBox.Visible = 'on';
                        rois = [];
                    else
                        rois = app.dFC_project.inlcude_hemi_sorted;
                    end
                    pause(0.01)
                    rois = sort(unique(app.rois(app.rois~=0)));
                    app.dFC_project.app_threshold = app.ThresholdSpinner.Value;
                    plot_on_brain_v3_inline_app
                end

            elseif strcmp(selectedButton.Text,'Temporal Metrics')
                cla(app.UIAxes)
                app.InterwindowSimilarityPanel.Visible = 'on';
                app.UITable.Visible = 'on';
                app.UIAxes.Visible = 'off';
                app.ThresholdSpinner.Visible = 'off';
                app.ThresholdSpinnerLabel.Visible = 'off';
                app.ColorSchemeDropDown.Visible = 'off';
                app.ColorSchemeDropDownLabel.Visible = 'off';
                app.AllRegionsCheckBox.Visible = 'off';
                app.LanguageNetworkCheckBox.Visible = 'off';
                app.DorsalAttentionNetworkCheckBox.Visible = 'off';
                app.SalienceNetworkCheckBox.Visible = 'off';
                app.DefaultModeNetworkCheckBox.Visible = 'off';
                app.UIAxes2.Visible = 'off';
                app.UIAxes3.Visible = 'off';
                app.UIAxes4.Visible = 'off';
                cla(app.UIAxes2)
                cla(app.UIAxes3)
                cla(app.UIAxes4)

                % Figure Control List Box
                nsub = app.dFC_project.nsub;
                choices = cellstr(num2str(transpose([1:nsub])));
                choices = cellfun(@(x) ['Subject ',x],choices,...
                    'UniformOutput',false);
                app.FigureCtrlListBox.Items = choices;
                app.FigureCtrlListBox.Value = app.FigureCtrlListBox.Items{1};
                % value = app.FigureCtrlListBox.Value;
                % choice = str2double(regexp(value,'\d*','Match'));
                app.HideOutlinesButton.Visible = 'on';
                app.TransitionThresholdSpinner.Visible = 'on';
                sub_value = app.FigureCtrlListBox.Value;
                subject = str2double(regexp(sub_value,'\d*','Match'));
                app.TransitionThresholdSpinner.Value = app.dFC_project.transition_threshold(subject);

            else
                errdlg('oops')
            end
        end

        % Value changed function: ThresholdSpinner
        function ThresholdSpinnerValueChanged(app, event)
            value = app.ThresholdSpinner.Value;
            app.dFC_project.app_threshold = value;
            cla(app.UIAxes)
            pause(0.01)
            value = app.FigureCtrlListBox.Value;
            state = str2double(regexp(value,'\d*','Match'));
            cla(app.UIAxes)
            nroi = size(app.dFC_project.dFC,1);
            if app.dFC_project.atlas==1 && nroi==166
                rois = [];
            else
                rois = app.dFC_project.include_hemi_sorted;
            end
            pause(0.01)
            rois = sort(unique(app.rois(app.rois~=0)));
            app.dFC_project.app_threshold = app.ThresholdSpinner.Value;
            plot_on_brain_v3_inline_app
        end

        % Value changed function: HideOutlinesButton
        function HideOutlinesButtonValueChanged(app, event)
            value = app.HideOutlinesButton.Value;
            if value
                value = app.FigureCtrlListBox.Value;
                subject = str2double(regexp(value,'\d*','Match'));
                upperlim = min(1,2*mean(app.dFC_project.comm_sim(:,:,subject),'all'));
                h = heatmap(app.InterwindowSimilarityPanel,...
                    app.dFC_project.comm_sim_states(:,:,subject), ...
                    'ColorLimits', [0 1],'Colormap',pink);
                h.GridVisible = 'off';
                labels = cell(size(app.dFC_project.comm_sim_states,1),1);
                labels(:) = {''};
                h.XDisplayLabels = labels;
                h.YDisplayLabels = labels;
                h.XLabel = 'Time Window';
                h.YLabel = 'Time Window';
                app.HideOutlinesButton.Text = 'Hide Outlines';
            else
                value = app.FigureCtrlListBox.Value;
                subject = str2double(regexp(value,'\d*','Match'));
                upperlim = min(1,2*mean(app.dFC_project.comm_sim(:,:,subject),'all'));
                h = heatmap(app.InterwindowSimilarityPanel,...
                    app.dFC_project.comm_sim(:,:,subject), ...
                    'ColorLimits', [0 1],'Colormap',pink);
                h.GridVisible = 'off';
                labels = cell(size(app.dFC_project.comm_sim_states,1),1);
                labels(:) = {''};
                h.XDisplayLabels = labels;
                h.YDisplayLabels = labels;
                h.XLabel = 'Time Window';
                h.YLabel = 'Time Window';
                app.HideOutlinesButton.Text = 'Show Outlines';
            end
        end

        % Value changed function: AllRegionsCheckBox
        function AllRegionsCheckBoxValueChanged(app, event)
            app.DynamicFunctionalConnectivityToolboxUIFigure.Pointer = 'watch';
            drawnow
            cla(app.UIAxes)
            cla(app.UIAxes4)
            value = app.FigureCtrlListBox.Value;
            state = str2double(regexp(value,'\d*','Match'));
            value = app.AllRegionsCheckBox.Value;
            if value
                app.LanguageNetworkCheckBox.Enable = 'off';
                app.DorsalAttentionNetworkCheckBox.Enable = 'off';
                app.SalienceNetworkCheckBox.Enable = 'off';
                app.DefaultModeNetworkCheckBox.Enable = 'off';
                rois = 1:166;
                app.dFC_project.app_threshold = app.ThresholdSpinner.Value;
                plot_on_brain_v3_inline_app
            else
                app.LanguageNetworkCheckBox.Enable = 'on';
                app.DorsalAttentionNetworkCheckBox.Enable = 'on';
                app.SalienceNetworkCheckBox.Enable = 'on';
                app.DefaultModeNetworkCheckBox.Enable = 'on';
                rois = sort(unique(app.rois(app.rois~=0)));
                app.dFC_project.app_threshold = app.ThresholdSpinner.Value;
                cla(app.UIAxes)
            end
            app.DynamicFunctionalConnectivityToolboxUIFigure.Pointer = 'arrow';
        end

        % Value changed function: LanguageNetworkCheckBox
        function LanguageNetworkCheckBoxValueChanged(app, event)
            app.DynamicFunctionalConnectivityToolboxUIFigure.Pointer = 'watch';
            drawnow
            cla(app.UIAxes)
            cla(app.UIAxes4)
            % app.comm = app.net_comm{1};
            % app.ncomm = app.net_ncomm{1};
            value = app.FigureCtrlListBox.Value;
            state = str2double(regexp(value,'\d*','Match'));
            value = app.LanguageNetworkCheckBox.Value;
            if value
                app.AllRegionsCheckBox.Enable = 'off';
                app.rois(167:200) = get_network(app.dFC_project,1).include;
                rois = sort(unique(app.rois(app.rois~=0)));
                app.dFC_project.app_threshold = app.ThresholdSpinner.Value;
                plot_on_brain_v3_inline_app
            else
                app.AllRegionsCheckBox.Enable = 'on';
                app.rois(167:200) = 0;
                rois = sort(unique(app.rois(app.rois~=0)));
                app.dFC_project.app_threshold = app.ThresholdSpinner.Value;
                plot_on_brain_v3_inline_app
            end
            app.DynamicFunctionalConnectivityToolboxUIFigure.Pointer = 'arrow';
        end

        % Value changed function: DorsalAttentionNetworkCheckBox
        function DorsalAttentionNetworkCheckBoxValueChanged(app, event)
            app.DynamicFunctionalConnectivityToolboxUIFigure.Pointer = 'watch';
            drawnow
            cla(app.UIAxes)
            cla(app.UIAxes4)
            % app.comm = app.net_comm{2};
            % app.ncomm = app.net_ncomm{2};
            value = app.FigureCtrlListBox.Value;
            state = str2double(regexp(value,'\d*','Match'));
            value = app.DorsalAttentionNetworkCheckBox.Value;
            if value
                app.AllRegionsCheckBox.Enable = 'off';
                app.rois(201:208) = get_network(app.dFC_project,3).include;
                rois = sort(unique(app.rois(app.rois~=0)));
                app.dFC_project.app_threshold = app.ThresholdSpinner.Value;
                plot_on_brain_v3_inline_app
            else
                app.AllRegionsCheckBox.Enable = 'on';
                app.rois(201:208) = 0;
                rois = sort(unique(app.rois(app.rois~=0)));
                app.dFC_project.app_threshold = app.ThresholdSpinner.Value;
                plot_on_brain_v3_inline_app
            end
            app.DynamicFunctionalConnectivityToolboxUIFigure.Pointer = 'arrow';
        end

        % Value changed function: SalienceNetworkCheckBox
        function SalienceNetworkCheckBoxValueChanged(app, event)
            app.DynamicFunctionalConnectivityToolboxUIFigure.Pointer = 'watch';
            drawnow
            cla(app.UIAxes)
            cla(app.UIAxes4)
            % app.comm = app.net_comm{3};
            % app.ncomm = app.net_ncomm{3};
            value = app.FigureCtrlListBox.Value;
            state = str2double(regexp(value,'\d*','Match'));
            value = app.SalienceNetworkCheckBox.Value;
            if value
                app.AllRegionsCheckBox.Enable = 'off';
                app.rois(209:216) = get_network(app.dFC_project,2).include;
                rois = sort(unique(app.rois(app.rois~=0)));
                app.dFC_project.app_threshold = app.ThresholdSpinner.Value;
                plot_on_brain_v3_inline_app
            else
                app.AllRegionsCheckBox.Enable = 'on';
                app.rois(209:216) = 0;
                rois = sort(unique(app.rois(app.rois~=0)));
                app.dFC_project.app_threshold = app.ThresholdSpinner.Value;
                plot_on_brain_v3_inline_app
            end
            app.DynamicFunctionalConnectivityToolboxUIFigure.Pointer = 'arrow';
        end

        % Value changed function: DefaultModeNetworkCheckBox
        function DefaultModeNetworkCheckBoxValueChanged(app, event)
            app.DynamicFunctionalConnectivityToolboxUIFigure.Pointer = 'watch';
            drawnow
            cla(app.UIAxes)
            cla(app.UIAxes4)
            % app.comm = app.net_comm{4};
            % app.ncomm = app.net_ncomm{4};
            value = app.FigureCtrlListBox.Value;
            state = str2double(regexp(value,'\d*','Match'));
            value = app.DefaultModeNetworkCheckBox.Value;
            if value
                app.AllRegionsCheckBox.Enable = 'off';
                app.rois(217:226) = get_network(app.dFC_project,4).include;
                rois = sort(unique(app.rois(app.rois~=0)));
                app.dFC_project.app_threshold = app.ThresholdSpinner.Value;
                plot_on_brain_v3_inline_app
            else
                app.AllRegionsCheckBox.Enable = 'on';
                app.rois(217:226) = 0;
                rois = sort(unique(app.rois(app.rois~=0)));
                app.dFC_project.app_threshold = app.ThresholdSpinner.Value;
                plot_on_brain_v3_inline_app
            end
            app.DynamicFunctionalConnectivityToolboxUIFigure.Pointer = 'arrow';
        end

        % Value changed function: ColorSchemeDropDown
        function ColorSchemeDropDownValueChanged(app, event)
            app.plot_color = app.ColorSchemeDropDown.Value;
            FigureCtrlListBoxValueChanged(app)
        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            app.dFC_project.var_output_dir =uigetdir(pwd,'Select folder for writing results');
            old_path = pwd;
            cd(app.dFC_project.var_output_dir)
            writematrix(app.dFC_project.mean_var,'mean_var.csv')
            writematrix(app.dFC_project.TF_comm_struct,'TF_comm_struct.csv')
            writematrix(app.dFC_project.mean_similarity,'mean_similarity.csv')
            % writematrix(app.dFC_project.mean_duration,'mean_duration.csv')
            % writematrix(app.dFC_project.time_in_state,'time_in_state.csv')
            % writematrix(app.dFC_project.mean_in_state_dFC,'mean_in_state_dFC.csv')
            % writematrix(app.dFC_project.mean_out_of_state_dFC,'mean_out_of_state_dFC.csv')
            for i=1:numel(app.dFC_project.vars)
                writematrix(app.dFC_project.vars{i},['vars_sub_',num2str(i),'.csv'])
            end
            if isfield(app.dFC_project,'state_occ')
                writematrix(app.dFC_project.state_occ,'state_occ.csv')
            end
            cd(old_path)
        end

        % Value changed function: TransitionThresholdSpinner
        function TransitionThresholdSpinnerValueChanged(app, event)
            value = app.TransitionThresholdSpinner.Value;
            sub_value = app.FigureCtrlListBox.Value;
            subject = str2double(regexp(sub_value,'\d*','Match'));
            app.dFC_project.transition_threshold(subject) = value;
            app.dFC_project = transition_freq(app.dFC_project);
            FigureCtrlListBoxValueChanged(app)
        end

        % Button pushed function: ExportCurrentFigureButton
        function ExportCurrentFigureButtonPushed(app, event)
            if strcmp(app.ViewModeButtonGroup.SelectedObject.Text,'Temporal Metrics')
                subject = app.FigureCtrlListBox.Value;
                subject = str2double(regexp(subject,'\d*','Match'));
                fig = figure();
                if app.HideOutlinesButton.Value
                    outline_opt = 'comm_sim_states';
                else
                    outline_opt = 'comm_sim';
                end
                h = heatmap(fig,...
                    app.dFC_project.(outline_opt)(:,:,subject), ...
                    'ColorLimits', [0 1]);
                h.GridVisible = 'off';
                labels = cell(size(app.dFC_project.comm_sim_states,1),1);
                labels(:) = {''};
                h.XDisplayLabels = labels;
                h.YDisplayLabels = labels;
                h.XLabel = 'Time Window';
                h.YLabel = 'Time Window';
                h.Colormap = pink;
                % x = app.center_x;
                % y = app.center_y;
                % msg = 'After making any desired customizations, use ''File'' menu to save figure.';
                % fig = uifigure('Position',[x-125 y-50 250 110]);
                % uitextarea(fig,'Value',msg,'Position',[10 60 230 40]);
                % cbx = uicheckbox(fig,'Text','Don''t show this message again','Position',[30 35 200 22]);
                % cbx.ValueChangedFcn = @(app,event) store_value(app,cbx.Value);
                % uibutton(fig,'Text','OK','ButtonPushedFcn',@(src,event) close(fig),'Position',[100 10 50 23]);
                
            elseif strcmp(app.FigureCtrlListBox.Value,'States Analysis Summary')
                f2 = figure('Position',[20 200 560 420]);
                f3 = figure('Position',[580 200 560 420]);
                copyUIAxes(app.UIAxes2,f2)
                copyUIAxes(app.UIAxes3,f3)
            else
                f1 = figure('Position',[20 200 900 300]);
                f4 = figure('Position',[580 200 208 184]);
                copyUIAxes(app.UIAxes,f1)
                copyUIAxes(app.UIAxes4,f4)
            end

            function store_value(app,value)
                app.user_pref.no_show = value;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DynamicFunctionalConnectivityToolboxUIFigure = uifigure('Visible', 'off');
            app.DynamicFunctionalConnectivityToolboxUIFigure.Name = 'Dynamic Functional Connectivity Toolbox';
            app.DynamicFunctionalConnectivityToolboxUIFigure.Icon = fullfile(pathToMLAPP, 'dFC_image.png');
            app.UIAxes = uiaxes(app.DynamicFunctionalConnectivityToolboxUIFigure);
            title(app.UIAxes, 'State Mean dFC')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.DynamicFunctionalConnectivityToolboxUIFigure);
            title(app.UIAxes2, 'PCA Plot')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.DynamicFunctionalConnectivityToolboxUIFigure);
            title(app.UIAxes3, 'Elbow Plot')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.Visible = 'off';
            app.UIAxes4 = uiaxes(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes4.Visible = 'off';
            app.StartnewprojectButton = uibutton(app.DynamicFunctionalConnectivityToolboxUIFigure, 'push');
            app.StartnewprojectButton.ButtonPushedFcn = createCallbackFcn(app, @StartnewprojectButtonPushed, true);
            app.StartnewprojectButton.Text = 'Start new project';
            app.LoadexistingprojectButton = uibutton(app.DynamicFunctionalConnectivityToolboxUIFigure, 'push');
            app.LoadexistingprojectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingprojectButtonPushed, true);
            app.LoadexistingprojectButton.Text = 'Load existing project';
            app.ComputeTemporalMetricsButton = uibutton(app.DynamicFunctionalConnectivityToolboxUIFigure, 'push');
            app.ComputeTemporalMetricsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeTemporalMetricsButtonPushed, true);
            app.ComputeTemporalMetricsButton.Enable = 'off';
            app.ComputeTemporalMetricsButton.Text = 'Compute Temporal Metrics';
            app.RunStatesAnalysisButton = uibutton(app.DynamicFunctionalConnectivityToolboxUIFigure, 'push');
            app.RunStatesAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunStatesAnalysisButtonPushed, true);
            app.RunStatesAnalysisButton.Enable = 'off';
            app.RunStatesAnalysisButton.Text = 'Run States Analysis ';
            app.Image = uiimage(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'dFC_image.png');
            app.ViewModeButtonGroup = uibuttongroup(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.ViewModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewModeButtonGroupSelectionChanged, true);
            app.ViewModeButtonGroup.Title = 'View Mode';
            app.ViewModeButtonGroup.Visible = 'off';
            app.TemporalMetricsButton = uiradiobutton(app.ViewModeButtonGroup);
            app.TemporalMetricsButton.Text = 'Temporal Metrics';
            app.TemporalMetricsButton.Value = true;
            app.StatesAnalysisButton = uiradiobutton(app.ViewModeButtonGroup);
            app.StatesAnalysisButton.Enable = 'off';
            app.StatesAnalysisButton.Text = 'States Analysis';
            app.InterwindowSimilarityPanel = uipanel(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.InterwindowSimilarityPanel.Title = 'Inter-window Similarity';
            app.InterwindowSimilarityPanel.Visible = 'off';
            app.FigureCtrlListBox = uilistbox(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.FigureCtrlListBox.Items = {};
            app.FigureCtrlListBox.ValueChangedFcn = createCallbackFcn(app, @FigureCtrlListBoxValueChanged, true);
            app.FigureCtrlListBox.Visible = 'off';
            app.FigureCtrlListBox.Value = {};
            app.ThresholdSpinnerLabel = uilabel(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.ThresholdSpinnerLabel.Visible = 'off';
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.ThresholdSpinner.Step = 0.05;
            app.ThresholdSpinner.Limits = [1e-06 Inf];
            app.ThresholdSpinner.ValueDisplayFormat = '%.2f';
            app.ThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @ThresholdSpinnerValueChanged, true);
            app.ThresholdSpinner.Visible = 'off';
            app.ThresholdSpinner.Value = 0.100001;
            app.HideOutlinesButton = uibutton(app.DynamicFunctionalConnectivityToolboxUIFigure, 'state');
            app.HideOutlinesButton.ValueChangedFcn = createCallbackFcn(app, @HideOutlinesButtonValueChanged, true);
            app.HideOutlinesButton.Visible = 'off';
            app.HideOutlinesButton.Text = 'Hide Outlines';
            app.HideOutlinesButton.Value = true;
            app.LanguageNetworkCheckBox = uicheckbox(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.LanguageNetworkCheckBox.ValueChangedFcn = createCallbackFcn(app, @LanguageNetworkCheckBoxValueChanged, true);
            app.LanguageNetworkCheckBox.Visible = 'off';
            app.LanguageNetworkCheckBox.Text = 'Language Network';
            app.DorsalAttentionNetworkCheckBox = uicheckbox(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.DorsalAttentionNetworkCheckBox.ValueChangedFcn = createCallbackFcn(app, @DorsalAttentionNetworkCheckBoxValueChanged, true);
            app.DorsalAttentionNetworkCheckBox.Visible = 'off';
            app.DorsalAttentionNetworkCheckBox.Text = 'Dorsal Attention Network';
            app.SalienceNetworkCheckBox = uicheckbox(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.SalienceNetworkCheckBox.ValueChangedFcn = createCallbackFcn(app, @SalienceNetworkCheckBoxValueChanged, true);
            app.SalienceNetworkCheckBox.Visible = 'off';
            app.SalienceNetworkCheckBox.Text = 'Salience Network';
            app.DefaultModeNetworkCheckBox = uicheckbox(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.DefaultModeNetworkCheckBox.ValueChangedFcn = createCallbackFcn(app, @DefaultModeNetworkCheckBoxValueChanged, true);
            app.DefaultModeNetworkCheckBox.Visible = 'off';
            app.DefaultModeNetworkCheckBox.Text = 'Default Mode Network';
            app.AllRegionsCheckBox = uicheckbox(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.AllRegionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllRegionsCheckBoxValueChanged, true);
            app.AllRegionsCheckBox.Visible = 'off';
            app.AllRegionsCheckBox.Text = 'All Regions';
            app.UITable = uitable(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.ExportCurrentFigureButton = uibutton(app.DynamicFunctionalConnectivityToolboxUIFigure, 'push');
            app.ExportCurrentFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCurrentFigureButtonPushed, true);
            app.ExportCurrentFigureButton.Enable = 'off';
            app.ExportCurrentFigureButton.Text = 'Export Current Figure';
            app.ColorSchemeDropDownLabel = uilabel(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.ColorSchemeDropDownLabel.Visible = 'off';
            app.ColorSchemeDropDownLabel.Text = 'Color Scheme';
            app.ColorSchemeDropDown = uidropdown(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.ColorSchemeDropDown.Items = {'default', 'redblue', 'earth', 'rycb', 'turbo', 'parula', 'hsv'};
            app.ColorSchemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorSchemeDropDownValueChanged, true);
            app.ColorSchemeDropDown.Visible = 'off';
            app.ColorSchemeDropDown.Value = 'default';
            app.ExportResultsButton = uibutton(app.DynamicFunctionalConnectivityToolboxUIFigure, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Enable = 'off';
            app.ExportResultsButton.Text = 'Export Results';
            app.TransitionThresholdSpinnerLabel = uilabel(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.TransitionThresholdSpinnerLabel.Visible = 'off';
            app.TransitionThresholdSpinnerLabel.Text = 'Transition Threshold';
            app.TransitionThresholdSpinner = uispinner(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.TransitionThresholdSpinner.Step = 0.01;
            app.TransitionThresholdSpinner.Limits = [0 1];
            app.TransitionThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @TransitionThresholdSpinnerValueChanged, true);
            app.TransitionThresholdSpinner.Visible = 'off';
            app.TextArea = uitextarea(app.DynamicFunctionalConnectivityToolboxUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            % Show the figure after all components are created
            app.DynamicFunctionalConnectivityToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dFC_toolbox_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DynamicFunctionalConnectivityToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DynamicFunctionalConnectivityToolboxUIFigure)
        end
    end
end